classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        FinalVoltageEditField       matlab.ui.control.NumericEditField
        FinalVoltageEditFieldLabel  matlab.ui.control.Label
        ScanButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        ConnectedtoLabel            matlab.ui.control.Label
        StepCountEditField          matlab.ui.control.NumericEditField
        StepCountEditFieldLabel     matlab.ui.control.Label
        StartVoltageEditField       matlab.ui.control.NumericEditField
        StartVoltageEditFieldLabel  matlab.ui.control.Label
        ModeDropDown                matlab.ui.control.DropDown
        ModeDropDownLabel           matlab.ui.control.Label
        DeviceDropDown              matlab.ui.control.DropDown
        DeviceDropDownLabel         matlab.ui.control.Label
        ConnectButton               matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        ALabel                      matlab.ui.control.Label
        MeasuredCurrentLabel        matlab.ui.control.Label
        VLabel                      matlab.ui.control.Label
        MeasuredVoltageLabel        matlab.ui.control.Label
        OutputLabel                 matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        visaDevices % stores the connected visa devices
        deviceSelected % stores the selected device
        visaObj % visa Object of the selected device
        volsweep = true; % voltage or current sweep
    end
    
    methods (Access = private)
        
        function voltageSweep(app)
            try
        
            % Define the voltage sweep parameters
            startVoltage = app.StartVoltageEditField.Value; % Starting voltage in volts
            endVoltage = app.FinalVoltageEditField.Value;   % Ending voltage in volts
            stepVoltage = app.StepCountEditField.Value; % Step size in volts
        
            % Generate the voltage sweep values
            voltageValues = startVoltage:stepVoltage:endVoltage;
        
            % Initialize an array to store the measured current
            measuredCurrent = zeros(size(voltageValues));
        
            % Perform the voltage sweep
            disp('Starting voltage sweep...');
            for i = 1:length(voltageValues)
                % Set the voltage using SCPI command
                fprintf(app.visaObj, [':SOUR:VOLT ', num2str(voltageValues(i))]);
        
                % Measure the current using SCPI command
                fprintf(app.visaObj, ':MEAS:CURR?');
                current = fscanf(app.visaObj);
        
                % Store the measured current
                measuredCurrent(i) = str2double(current);
        
                % Display the result
                disp(['Voltage: ', num2str(voltageValues(i)), ' V, Current: ', current, ' A']);
            end
        
            % Plot the voltage vs. current
            plot(app.UIAxes, voltageValues, measuredCurrent, '-o');
            app.UIAxes.XLabel('Voltage (V)');
            app.UIAxes.YLabel('Current (A)');
            app.UIAxes.title('Voltage Sweep');
            grid on;
        
            catch ME
            % Handle errors and clean up
            disp('An error occurred:');
            disp(ME.message);   
            end
        end

        function currentSweep(app)
            try
        
            % Define the current sweep parameters
            startCurrent = app.StartVoltageEditField.Value; % Starting current in amps
            endCurrent = app.FinalVoltageEditField.Value;   % Ending current in amps
            stepCurrent = app.StepCountEditField.Value; % Step size in amps
        
            % Generate the current sweep values
            currentValues = startCurrent:stepCurrent:endCurrent;
        
            % Initialize an array to store the measured voltage
            measuredVoltage = zeros(size(currentValues));
        
            % Perform the current sweep
            disp('Starting voltage sweep...');
            for i = 1:length(currentValues)
                % Set the current using SCPI command
                fprintf(app.visaObj, [':SOUR:CURR ', num2str(currentValues(i))]);
        
                % Measure the voltage using SCPI command
                fprintf(app.visaObj, ':MEAS:VOLT?');
                voltage = fscanf(app.visaObj);
        
                % Store the measured voltage
                measuredVoltage(i) = str2double(voltage);
        
                % Display the result
                disp(['Current: ', num2str(currentValues(i)), ' V, Voltage: ', voltage, ' A']);
            end
        
            % Plot the current vs. voltage
            plot(app.UIAxes, currentValues, measuredVoltage, '-o');
            app.UIAxes.XLabel('Current (A)');
            app.UIAxes.YLabel('Voltage (A)');
            app.UIAxes.title('Current Sweep');
            grid on;
        
            catch ME
            % Handle errors and clean up
            disp('An error occurred:');
            disp(ME.message);   
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            try
                vinfo = instrhwinfo('visa', 'NI');
                app.visaDevices = vinfo.ObjectConstructorName;
                app.visaDevices = string(app.visaDevices);

                app.DeviceDropDown.Items = app.visaDevices;
                app.DeviceDropDown.ItemsData = app.visaDevices;
            catch Exc
                app.ConnectedtoLabel.Text = "No Devices Found";
                disp(Exc.message);
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.deviceSelected = app.DeviceDropDown.Value;
            try
                app.visaObj = eval(app.deviceSelected.ObjectConstrutorName);
                fopen(app.visaObj);
                app.ConnectedtoLabel.Text = "Successfully connected to " + app.visaObj;
            catch
                app.ConnectedtoLabel.Text = "Something Went Wrong. Please try again";
            end
        end

        % Value changed function: StartVoltageEditField
        function StartVoltageEditFieldValueChanged(app, event)
            fprintf(app.visaObj, [':SOUR:CURR ', app.StartVoltageEditField.Value]);
            fprintf(app.visaObj, [':MEAS:CURR?']);
            app.MeasuredCurrentLabel.Text = fscanf(app.visaObj);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if strcmp(app.ModeDropDown.Value, "Voltage Sweep")
                app.voltageSweep;
            else
                
            end
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            if strcmp(app.ModeDropDown.Value, "Voltage Sweep")
                app.StartVoltageEditFieldLabel.Text = "Start Voltage";
                app.FinalVoltageEditFieldLabel.Text = "Final Voltage";
            else
                app.StartVoltageEditFieldLabel.Text = "Start Current";
                app.FinalVoltageEditFieldLabel.Text = "Final Current";
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DeviceDropDownLabel = uilabel(app.LeftPanel);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.LeftPanel);
            app.DeviceDropDown.Items = {'None'};
            app.DeviceDropDown.Value = 'None';
            app.ModeDropDownLabel = uilabel(app.LeftPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.LeftPanel);
            app.ModeDropDown.Items = {'Voltage Sweep', 'Current Sweep'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Voltage Sweep';
            app.StartVoltageEditFieldLabel = uilabel(app.LeftPanel);
            app.StartVoltageEditFieldLabel.Text = 'Start Voltage';
            app.StartVoltageEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StartVoltageEditField.ValueChangedFcn = createCallbackFcn(app, @StartVoltageEditFieldValueChanged, true);
            app.StepCountEditFieldLabel = uilabel(app.LeftPanel);
            app.StepCountEditFieldLabel.Text = 'Step Count';
            app.StepCountEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ConnectedtoLabel = uilabel(app.LeftPanel);
            app.ConnectedtoLabel.Text = 'Connected to :';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ScanButton = uibutton(app.LeftPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.FinalVoltageEditFieldLabel = uilabel(app.LeftPanel);
            app.FinalVoltageEditFieldLabel.Text = 'Final Voltage';
            app.FinalVoltageEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'current')
            ylabel(app.UIAxes, 'voltage')
            zlabel(app.UIAxes, 'Z')
            app.OutputLabel = uilabel(app.RightPanel);
            app.OutputLabel.Text = 'Output';
            app.MeasuredVoltageLabel = uilabel(app.RightPanel);
            app.MeasuredVoltageLabel.Text = 'Measured Voltage : ';
            app.VLabel = uilabel(app.RightPanel);
            app.VLabel.Text = '0 V';
            app.MeasuredCurrentLabel = uilabel(app.RightPanel);
            app.MeasuredCurrentLabel.Text = 'Measured Current : ';
            app.ALabel = uilabel(app.RightPanel);
            app.ALabel.Text = '0 A';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end